classdef Grid_Forming_Converter_Performance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TimeScope2_2        matlab.ui.scope.TimeScope
        TimeScope2          matlab.ui.scope.TimeScope
        TimeScope_2         matlab.ui.scope.TimeScope
        TimeScope           matlab.ui.scope.TimeScope
        SaveOutputButton    simulink.ui.control.SaveOutputButton
        GridFormingInverterPerformanceLabel  matlab.ui.control.Label
        VariableTuner       simulink.ui.control.VariableTuner
        SimulationControls  simulink.ui.control.SimulationControls
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UIAxesButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            app.VariableTuner = uisimvartuner(app.UIFigure);
            app.VariableTuner.Simulation = app.Simulation;
            app.GridFormingInverterPerformanceLabel = uilabel(app.UIFigure);
            app.GridFormingInverterPerformanceLabel.Text = 'Grid-Forming Inverter Performance';
            app.SaveOutputButton = uisimdatabutton('saveoutput', app.UIFigure);
            app.SaveOutputButton.Simulation = app.Simulation;
            app.TimeScope = uitimescope(app.UIFigure);
            app.TimeScope.BufferLength = 10000;
            app.TimeScope.LegendNames = {'Va'; 'Vb'; 'Vc'};
            app.TimeScope.LegendVisible = 'on';
            app.TimeScope.Title = 'Vabc';
            app.TimeScope.YLabel = 'V';
            app.TimeScope.XLimits = [0 1];
            app.TimeScope.YLimits = [-380 380];
            app.TimeScope.XTimeSpan = 1;
            app.TimeScope.XTimeUnits = 'none';
            app.TimeScope_2 = uitimescope(app.UIFigure);
            app.TimeScope_2.BufferLength = 10000;
            app.TimeScope_2.LegendNames = {'Ia'; 'Ib'; 'Ic'};
            app.TimeScope_2.LegendVisible = 'on';
            app.TimeScope_2.Title = 'Iabc';
            app.TimeScope_2.YLabel = 'A';
            app.TimeScope_2.XLimits = [0 1];
            app.TimeScope_2.YLimits = [-210 210];
            app.TimeScope_2.YLimitsMethod = 'manual';
            app.TimeScope_2.XTimeSpan = 1;
            app.TimeScope_2.XTimeUnits = 'none';
            app.TimeScope2 = uitimescope(app.UIFigure);
            app.TimeScope2.BufferLength = 10000;
            app.TimeScope2.LegendNames = {'Q'; 'P'};
            app.TimeScope2.LegendVisible = 'on';
            app.TimeScope2.Title = 'PQ';
            app.TimeScope2.YLabel = 'W';
            app.TimeScope2.XLimits = [0 1];
            app.TimeScope2.YLimits = [0 80000];
            app.TimeScope2.YLimitsMethod = 'manual';
            app.TimeScope2.XTimeSpan = 1;
            app.TimeScope2.XTimeUnits = 'none';
            app.TimeScope2_2 = uitimescope(app.UIFigure);
            app.TimeScope2_2.BufferLength = 10000;
            app.TimeScope2_2.LegendNames = {'w'};
            app.TimeScope2_2.LegendVisible = 'on';
            app.TimeScope2_2.Title = 'w';
            app.TimeScope2_2.YLabel = 'rad/s';
            app.TimeScope2_2.XLimits = [0 1];
            app.TimeScope2_2.YLimits = [0 55];
            app.TimeScope2_2.YLimitsMethod = 'manual';
            app.TimeScope2_2.XTimeSpan = 1;
            app.TimeScope2_2.XTimeUnits = 'none';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Demux3:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Demux3:2'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Demux3:3'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Demux4:1'], app.TimeScope_2);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Demux4:2'], app.TimeScope_2);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Demux4:3'], app.TimeScope_2);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/From15:1'], app.TimeScope2);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/From16:1'], app.TimeScope2);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Gain:1'], app.TimeScope2_2);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Grid_Forming_Converter_Performance

            % Associate the Simulink Model
            app.Simulation = simulation('GFM_droop_Completed');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end